classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BackBtnInTicket            matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LaunchTab                  matlab.ui.container.Tab
        LetsbookyourtrainwithjustatapLabel  matlab.ui.control.Label
        BuyTicketsButton           matlab.ui.control.Button
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        QueryTab                   matlab.ui.container.Tab
        usrStausLabel              matlab.ui.control.Label
        recentTrainTime            matlab.ui.control.Label
        recentTrainCode            matlab.ui.control.Label
        CheckBox                   matlab.ui.control.CheckBox
        Image13                    matlab.ui.control.Image
        MyTicketsButton            matlab.ui.control.Button
        isSelfTimeCheckBox         matlab.ui.control.CheckBox
        HTML                       matlab.ui.control.HTML
        MinuteSpinner              matlab.ui.control.Spinner
        MinuteSpinnerLabel         matlab.ui.control.Label
        HourSpinner                matlab.ui.control.Spinner
        HourSpinnerLabel           matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        SelectDestinationPanel     matlab.ui.container.Panel
        ToStationDropDown          matlab.ui.control.DropDown
        FromDropDownLabel_2        matlab.ui.control.Label
        StartStationDropDown       matlab.ui.control.DropDown
        FromDropDownLabel          matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        SearchedTicketsTab         matlab.ui.container.Tab
        SortByPriceBtn             matlab.ui.control.Button
        SortByEarilestButton       matlab.ui.control.Button
        SortByTimeButton           matlab.ui.control.Button
        Image11_2                  matlab.ui.control.Image
        TabDorT                    matlab.ui.container.TabGroup
        TabD                       matlab.ui.container.Tab
        DGrid                      matlab.ui.container.GridLayout
        Panel_tickets_D_3          matlab.ui.container.Panel
        Ticket_price_3             matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        bookTicketBtn3             matlab.ui.control.Button
        ticket1ToTime_6            matlab.ui.control.Label
        ticket1StartTime_6         matlab.ui.control.Label
        ticket1Train_6             matlab.ui.control.Label
        ticket1ToStation_6         matlab.ui.control.Label
        ticket1StartStation_6      matlab.ui.control.Label
        Image8_5                   matlab.ui.control.Image
        Panel_tickets_D_2          matlab.ui.container.Panel
        Label_3                    matlab.ui.control.Label
        Ticket_price_2             matlab.ui.control.Label
        bookTicketBtn2             matlab.ui.control.Button
        ticket1ToTime_11           matlab.ui.control.Label
        ticket1StartTime_11        matlab.ui.control.Label
        ticket1Train_11            matlab.ui.control.Label
        ticket1ToStation_11        matlab.ui.control.Label
        ticket1StartStation_11     matlab.ui.control.Label
        Image8_10                  matlab.ui.control.Image
        Panel_tickets_D_1          matlab.ui.container.Panel
        Ticket_price_1             matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        bookTicketBtn1             matlab.ui.control.Button
        ticket1ToTime_10           matlab.ui.control.Label
        ticket1StartTime_10        matlab.ui.control.Label
        ticket1Train_10            matlab.ui.control.Label
        ticket1ToStation_10        matlab.ui.control.Label
        ticket1StartStation_10     matlab.ui.control.Label
        Image8_9                   matlab.ui.control.Image
        TabT                       matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        TTrain3                    matlab.ui.container.Panel
        Ticket_price_6             matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        bookTicketBtn6             matlab.ui.control.Button
        ticket1ToTime_9            matlab.ui.control.Label
        ticket1StartTime_9         matlab.ui.control.Label
        ticket1Train_9             matlab.ui.control.Label
        ticket1ToStation_9         matlab.ui.control.Label
        ticket1StartStation_9      matlab.ui.control.Label
        Image8_8                   matlab.ui.control.Image
        TTrain2                    matlab.ui.container.Panel
        Ticket_price_5             matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        bookTicketBtn5             matlab.ui.control.Button
        ticket1ToTime_8            matlab.ui.control.Label
        ticket1StartTime_8         matlab.ui.control.Label
        ticket1Train_8             matlab.ui.control.Label
        ticket1ToStation_8         matlab.ui.control.Label
        ticket1StartStation_8      matlab.ui.control.Label
        Image8_7                   matlab.ui.control.Image
        TTrain1                    matlab.ui.container.Panel
        Ticket_price_4             matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        bookTicketBtn4             matlab.ui.control.Button
        ticket1ToTime_7            matlab.ui.control.Label
        ticket1StartTime_7         matlab.ui.control.Label
        ticket1Train_7             matlab.ui.control.Label
        ticket1ToStation_7         matlab.ui.control.Label
        ticket1StartStation_7      matlab.ui.control.Label
        Image8_6                   matlab.ui.control.Image
        Tickets_Stop_station       matlab.ui.control.Label
        Tickets_Start_station      matlab.ui.control.Label
        Image7                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        BookTab                    matlab.ui.container.Tab
        selectVIPICON              matlab.ui.control.Image
        selectNormalICON           matlab.ui.control.Image
        VIPSeatImg                 matlab.ui.control.Image
        normalSeatImg              matlab.ui.control.Image
        NoSeatNormalICON_2         matlab.ui.control.Image
        NoSeatNormalICON_1         matlab.ui.control.Image
        NoSeatVIPICON              matlab.ui.control.Image
        DropDown_SelectPassanger   matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        Image11                    matlab.ui.control.Image
        ticket1ToTime_2            matlab.ui.control.Label
        ticket1StartTime_2         matlab.ui.control.Label
        ticket1Train_2             matlab.ui.control.Label
        ticket1ToStation_2         matlab.ui.control.Label
        bookticketStartStation     matlab.ui.control.Label
        BookBtn                    matlab.ui.control.Button
        Image6                     matlab.ui.control.Image
        UsrTab                     matlab.ui.container.Tab
        myticket_inputID_checkBox  matlab.ui.control.DropDown
        InputIDDropDownLabel       matlab.ui.control.Label
        backBtnInMytickets         matlab.ui.control.Image
        UITable                    matlab.ui.control.Table
        usrTicket                  matlab.ui.container.Panel
        GetTrainBtn                matlab.ui.control.Button
        MyticketCancelBtn          matlab.ui.control.Button
        MyticketRescheduleBtn      matlab.ui.control.Button
        MyticketToTime             matlab.ui.control.Label
        MyticketStartTime          matlab.ui.control.Label
        MyticketTrain              matlab.ui.control.Label
        MyticketToStation          matlab.ui.control.Label
        MyticketStartStation       matlab.ui.control.Label
        Image8_11                  matlab.ui.control.Image
        Image12                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        trainDispatch % Description
        debugApp;
        usrID; %用户唯一标识符
        myTickets=[];
    end
    
    properties (Access = private)

        level2Book=0; % Description
        remainingSeats; % Description
        searchedTickets;
    end
    
    methods (Access = public)
        function showTickets(app,tickets)
            "将可能之前隐藏的车票都先显示";
            app.TTrain3.Visible=true;
            app.TTrain2.Visible=true;
            app.TTrain1.Visible=true;
            app.Panel_tickets_D_3.Visible=true;
            app.Panel_tickets_D_2.Visible=true;
            app.Panel_tickets_D_1.Visible=true;


            startStation=app.StartStationDropDown.Value;
            stopStation=app.ToStationDropDown.Value;

            dIndex=1;
            tIndex=1;
            
            if tickets.length==0
                "找不到任何车票！！！"
            end
            if tickets.length>=1
                for i=1:tickets.length
                    trains=tickets(i);
                    if contains(trains, ',')
                        "含有转乘";
                        strains=split(trains, ',');

                        startTime=app.trainDispatch.findTrain(strains(1)).getStationDepartureTime(Station(startStation));
                        ToTime=app.trainDispatch.findTrain(strains(end)).getStationArrrivalTime(Station(stopStation));
                        price=app.trainDispatch.getPriceForTransferTrains(strains,Station(startStation),Station(stopStation));
                        app.writeTicketPanel(2,tIndex,startStation,startTime,stopStation,ToTime,trains,price);
                        tIndex=tIndex+1;
                    else
                        "直达列车";
                        thetrain=app.trainDispatch.findTrain(trains);
                        startTime=thetrain.getStationDepartureTime(Station(startStation));
                        ToTime=thetrain.getStationArrrivalTime(Station(stopStation));
                        price=thetrain.getPrice(Station(startStation),Station(stopStation));
                        app.writeTicketPanel(1,dIndex,startStation,startTime,stopStation,ToTime,trains,price);
                        dIndex=dIndex+1;
                    end
                    

                end

                "将没有信息的车票隐藏";
                if tIndex<=3
                    app.TTrain3.Visible=false;
                end
                if tIndex<=2
                    app.TTrain2.Visible=false;
                end
                if tIndex<=1
                    app.TTrain1.Visible=false;
                end

                if dIndex<=3
                    app.Panel_tickets_D_3.Visible=false;
                end
                if dIndex<=2
                    app.Panel_tickets_D_2.Visible=false;
                end
                if dIndex<=1
                    app.Panel_tickets_D_1.Visible=false;
                end


            end 
        end

        function writeTicketPanel(app,DorT,index,startStation,startTime,ToStation,ToTime,trainCode,price)
            if DorT==1
                % "直达"
                switch index
                    case 1
                        app.ticket1Train_10.Text=trainCode;
                        app.ticket1StartStation_10.Text=startStation;
                        app.ticket1StartTime_10.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_10.Text=ToStation;
                        app.ticket1ToTime_10.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_1.Text=string(price);
                    case 2
                        app.ticket1Train_11.Text=trainCode;
                        app.ticket1StartStation_11.Text=startStation;
                        app.ticket1StartTime_11.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_11.Text=ToStation;
                        app.ticket1ToTime_11.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_2.Text=string(price);
                    case 3
                        app.ticket1Train_6.Text=trainCode;
                        app.ticket1StartStation_6.Text=startStation;
                        app.ticket1StartTime_6.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_6.Text=ToStation;
                        app.ticket1ToTime_6.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_3.Text=string(price);


                end
            else
                % "转成"
                switch index
                    case 1
                        app.ticket1Train_7.Text=trainCode;
                        app.ticket1StartStation_7.Text=startStation;
                        app.ticket1StartTime_7.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_7.Text=ToStation;
                        app.ticket1ToTime_7.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_4.Text=string(price);
                    case 2
                        app.ticket1Train_8.Text=trainCode;
                        app.ticket1StartStation_8.Text=startStation;
                        app.ticket1StartTime_8.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_8.Text=ToStation;
                        app.ticket1ToTime_8.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_5.Text=string(price);
                    case 3
                        app.ticket1Train_9.Text=trainCode;
                        app.ticket1StartStation_9.Text=startStation;
                        app.ticket1StartTime_9.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_9.Text=ToStation;
                        app.ticket1ToTime_9.Text=datestr(ToTime, 'HH:MM');
                        app.Ticket_price_6.Text=string(price);


                end


            end
        end
    end
    
    methods (Access = private)
        
        function refreshSeatOccupyUI(app)
            startStation=app.ticket1StartStation_10.Text;
            toStation=app.ticket1ToStation_10.Text;
            trainCode=app.ticket1Train_2.Text;
            trainCode=app.getTheFirstTrain(trainCode);
             "TODO 这里只取了第一个"
            app.remainingSeats=app.trainDispatch.findTrain(trainCode).requestAvailableSeats(Station(startStation),Station(toStation));
            app.NoSeatVIPICON.Visible=(app.remainingSeats(1)==0);
            app.NoSeatNormalICON_2.Visible=(app.remainingSeats(2)==0);
            app.NoSeatNormalICON_1.Visible=(app.remainingSeats(2)<2);
            
        end
        
        function bookTicketCommon(app,trainCode,startStation,toStation,startTime,toTime)

            if contains(trainCode,",")
                "有转乘坐"
                trains=strsplit(trainCode,",");
                remain=[10,10];
                for i=1:length(trains)
                    thetrain=trains(i);
                    thisRemaining=app.trainDispatch.findTrain(thetrain).requestAvailableSeats(Station(startStation),Station(toStation));
                    remain(1)=min(remain(1),thisRemaining(1));
                    remain(2)=min(remain(2),thisRemaining(2));
                end
                app.remainingSeats=remain;
            else
                app.remainingSeats=app.trainDispatch.findTrain(trainCode).requestAvailableSeats(Station(startStation),Station(toStation));
            end


            app.bookticketStartStation.Text=startStation;
            app.ticket1StartTime_2.Text=startTime;
            app.ticket1Train_2.Text=trainCode;
            app.ticket1ToStation_2.Text=toStation;
            app.ticket1ToTime_2.Text=toTime;


            if app.remainingSeats(2)==0
                "没有经济舱了，默认选择商务舱";
                app.level2Book=1;
                app.selectNormalICON.Visible=false;
                app.selectVIPICON.Visible=true;
                
            else
                app.level2Book=2;
                app.selectNormalICON.Visible=true;
                app.selectVIPICON.Visible=false;

            end

            app.refreshSeatOccupyUI();
            % 找到要删除的元素的索引 
            indexToRemove = strcmp(app.DropDown_SelectPassanger.Items, app.usrID);
            app.DropDown_SelectPassanger.Items(indexToRemove)=[];
            app.TabGroup.SelectedTab = app.BookTab;

            
        end
        
        function restoreMyTicketBtn(app)
            disp("恢复MyTicketBtn")
            app.MyTicketsButton.Text="My Tickets";
            app.MyTicketsButton.Enable=true;
            
        end

        
        function results = getTheFirstTrain(~,trains)
            results=trains;
             if contains(trains,",")
                trains=strsplit(trains,",");
                results=trains(1);
                results=results{1};
             end
        end
        
        function showRecentTicket(app)
            if isempty(app.myTickets)
                return
            end
            earTIme=datetime("23:59:59");
            earIndex=0;
            [row_num, ~] = size(app.myTickets);
            for i=1:row_num
                ticket=app.myTickets(i,:);
                time=datetime(ticket(4).stationName, 'Format', 'HH:mm');
                if time<earTIme
                    earTIme=time;
                    earIndex=i;
                end

            end

            app.recentTrainCode.Text=app.myTickets(earIndex,1).stationName;
            app.recentTrainTime.Text=app.myTickets(earIndex,4).stationName;


        end
        
        function results = sort3TicketsByEariest(app,train1,train2,train3,time1,time2,time3)
            if strcmp(time2,"Label2")
                results=[train1];
                return
            end
            time1=datetime(time1, 'Format', 'HH:mm');
            time2=datetime(time2, 'Format', 'HH:mm');
            sortedTickets=[];
            if strcmp(app.ticket1ToTime_6.Text,"Label2")
                
                if time1<=time2 
                    sortedTickets=[train1,train2];
                else
                    sortedTickets=[train2,train1];
                end
                
            else
                time3=datetime(time3, 'Format', 'HH:mm');
    
                if time1<=time2 && time1<=time3
                    if time2<=time3
                        sortedTickets=[train1,train2,train3];
                    else
                        sortedTickets=[train1,train3,train2];
                    end
                elseif time2<=time1 && time2<=time3
                    if time1<=time3
                        sortedTickets=[train2,train1,train3];
                    else
                        sortedTickets=[train2,train3,train1];
                    end
                elseif time3<=time1 && time3<=time2
                    if time1<=time2
                        sortedTickets=[train3,train1,train2];
                    else
                        sortedTickets=[train3,train2,train1];
                    end
                end
                
            end
            results=sortedTickets;
        end
        
        function results = sort3TicketsByAllTime(app,train1,train2,train3,time1,time11,time2,time22,time3,time33)
            if strcmp(time2,"Label2")
                results=[train1];
                return
            end
            time1_start=datetime(time1, 'Format', 'HH:mm');
            time1_end=datetime(time11, 'Format', 'HH:mm');
            time2_start=datetime(time2, 'Format', 'HH:mm');
            time2_end=datetime(time22, 'Format', 'HH:mm');
            total_time1=minutes(time1_end-time1_start);
            total_time2=minutes(time2_end-time2_start);
            sortedTickets=[];

            if strcmp(time3,"Label2")
                
                if total_time1<=total_time2 
                    sortedTickets=[train1,train2];
                else
                    sortedTickets=[train2,train1];
                end
                
            else

            time3_start=datetime(time3, 'Format', 'HH:mm');
            time3_end=datetime(time33, 'Format', 'HH:mm');
            
            
            total_time3=hours(time3_end-time3_start);


                if total_time1<=total_time2 && total_time1<=total_time3
                    if total_time2<=total_time3
                        sortedTickets=[train1,train2,train3];
                    else
                        sortedTickets=[train1,train3,train2];
                    end
                elseif total_time2<=total_time1 && total_time2<=total_time3
                    if total_time1<=total_time3
                        sortedTickets=[train2,train1,train3];
                    else
                        sortedTickets=[train2,train3,train1];
                    end
                elseif total_time3<=total_time1 && total_time3<=total_time2
                    if total_time1<=total_time2
                        sortedTickets=[train3,train1,train2];
                    else
                        sortedTickets=[train3,train2,train1];
                    end
                end
            end
            
            results=sortedTickets;
        end
    
    
        function results= sort3TicketsByPrice(app,train1,train2,train3,time1,time2,time3)

            if strcmp(time2,"Label6")
                results=[train1];
                return
            end
            time1=str2double(time1);
            time2=str2double(time2);
            sortedTickets=[];
            if strcmp(time3,"Label6")
                
                if time1<=time2 
                    sortedTickets=[train1,train2];
                else
                    sortedTickets=[train2,train1];
                end
                
            else
                time3=str2double(time3);
    
                if time1<=time2 && time1<=time3
                    if time2<=time3
                        sortedTickets=[train1,train2,train3];
                    else
                        sortedTickets=[train1,train3,train2];
                    end
                elseif time2<=time1 && time2<=time3
                    if time1<=time3
                        sortedTickets=[train2,train1,train3];
                    else
                        sortedTickets=[train2,train3,train1];
                    end
                elseif time3<=time1 && time3<=time2
                    if time1<=time2
                        sortedTickets=[train3,train1,train2];
                    else
                        sortedTickets=[train3,train2,train1];
                    end
                end
                
            end
            results=sortedTickets;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuyTicketsButton
        function BuyTicketsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
            app.showRecentTicket();
        end

        % Callback function
        function DebugButtonPushed(app, event)

        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            startStation=Station(app.StartStationDropDown.Value);
            if ~app.isSelfTimeCheckBox.Value
                startStation.arrivalTime=app.trainDispatch.SysTime;
            else
                timestr=sprintf('%02d:%02d:00', app.HourSpinner.Value, app.MinuteSpinner.Value);
                startStation.arrivalTime=datetime(timestr);
            end
            
            toStation=Station(app.ToStationDropDown.Value);
            SearchedTickets=app.trainDispatch.findAvailableTickets(startStation,toStation,0);
            "搜索到的车票为";
            disp(SearchedTickets)
            tickets=strsplit(SearchedTickets,"-");
             disp(tickets(1:end-1))
             app.searchedTickets=tickets(1:end-1);
            app.showTickets(app.searchedTickets);
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;
        end

        % Callback function: BackBtnInTicket, Image11_2
        function BackBtnInTicketPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
            app.showRecentTicket();
        end

        % Image clicked function: Image11
        function BackBtnInBookButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;
            app.BookBtn.Text="订票";
            app.BookBtn.Enable=true;
        end

        % Button pushed function: bookTicketBtn1
        function bookTicketBtn1ButtonPushed(app, event)
             disp("第一个车票订票逻辑")
            startStation=app.ticket1StartStation_10.Text;
            toStation=app.ticket1ToStation_10.Text;
            trainCode=app.ticket1Train_10.Text;
            startTime=app.ticket1StartTime_10.Text;
            toTime=app.ticket1ToTime_10.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Image clicked function: normalSeatImg
        function normalSeatImgImageClicked(app, event)
            app.level2Book=2;
            app.selectNormalICON.Visible=true;
            app.selectVIPICON.Visible=false;
        end

        % Image clicked function: VIPSeatImg
        function VIPSeatImgImageClicked(app, event)
            app.level2Book=1;
            app.selectNormalICON.Visible=false;
            app.selectVIPICON.Visible=true;
        end

        % Button pushed function: BookBtn
        function BookBtnButtonPushed(app, event)
            isOtherPassenger=0;
            if app.DropDown_SelectPassanger.Value~="就我自己"
                isOtherPassenger=1;
            end
            if app.remainingSeats(app.level2Book)-isOtherPassenger<=0
                app.BookBtn.Text="买不到票啦换其他的吧！";
                return;
            end
            
            startStation=Station(app.ticket1StartStation_10.Text);
            toStation=Station(app.ticket1ToStation_10.Text);
            trainCode=app.ticket1Train_2.Text;
            trainCode=app.getTheFirstTrain(trainCode);
             "TODO 这里只取了第一个"
            app.trainDispatch.findTrain(trainCode).bookTicket(startStation,toStation,app.level2Book,isOtherPassenger+1);
            app.refreshSeatOccupyUI();
            app.BookBtn.Text="订票成功！请返回";
            app.BookBtn.Enable=false;
            app.debugApp.displaySeats();

            % "记录我购买的车票"
            
            app.myTickets=[app.myTickets;[app.ticket1Train_2.Text,startStation,toStation,app.ticket1StartTime_2.Text,app.ticket1ToTime_2.Text,string(isOtherPassenger+1),app.level2Book]];


        end

        % Button pushed function: bookTicketBtn2
        function bookTicketBtn2ButtonPushed(app, event)
            "第二个车票订票逻辑";
            startStation=app.ticket1StartStation_11.Text;
            toStation=app.ticket1ToStation_11.Text;
            trainCode=app.ticket1Train_11.Text;
            startTime=app.ticket1StartTime_11.Text;
            toTime=app.ticket1ToTime_11.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Button pushed function: bookTicketBtn3
        function bookTicketBtn3ButtonPushed(app, event)
            "第三个车票订票逻辑";
            startStation=app.ticket1StartStation_6.Text;
            toStation=app.ticket1ToStation_6.Text;
            trainCode=app.ticket1Train_6.Text;
            startTime=app.ticket1StartTime_6.Text;
            toTime=app.ticket1ToTime_6.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Button pushed function: bookTicketBtn4
        function bookTicketBtn4ButtonPushed(app, event)
            "第四个车票订票逻辑";
            startStation=app.ticket1StartStation_7.Text;
            toStation=app.ticket1ToStation_7.Text;
            trainCode=app.ticket1Train_7.Text;
            startTime=app.ticket1StartTime_7.Text;
            toTime=app.ticket1ToTime_7.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Button pushed function: bookTicketBtn5
        function bookTicketBtn5ButtonPushed(app, event)
            "第五个车票订票逻辑";
            startStation=app.ticket1StartStation_8.Text;
            toStation=app.ticket1ToStation_8.Text;
            trainCode=app.ticket1Train_8.Text;
            startTime=app.ticket1StartTime_8.Text;
            toTime=app.ticket1ToTime_8.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Button pushed function: bookTicketBtn6
        function bookTicketBtn6ButtonPushed(app, event)
            "第六个车票订票逻辑";
            startStation=app.ticket1StartStation_9.Text;
            toStation=app.ticket1ToStation_9.Text;
            trainCode=app.ticket1Train_9.Text;
            startTime=app.ticket1StartTime_9.Text;
            toTime=app.ticket1ToTime_9.Text;

            app.bookTicketCommon(trainCode,startStation,toStation,startTime,toTime);
        end

        % Button pushed function: MyTicketsButton
        function MyTicketsButtonPushed(app, event)
            if isempty(app.myTickets)
                disp("你没有车票看个卵")
                app.MyTicketsButton.Text="没有车票哦";
                app.MyTicketsButton.Enable=false;

                pause(2);
                app.restoreMyTicketBtn();
                return
            end
            
            "填充表格"
            app.UITable.Data={};
            [row_num, ~] = size(app.myTickets);

            for i=1:row_num
                app.UITable.Data=[app.UITable.Data;{i,app.myTickets(i,1).stationName}];
            end
            "填充ID选择器"
            app.myticket_inputID_checkBox.Items=["请选择",string(1:row_num)];
            

            app.TabGroup.SelectedTab = app.UsrTab;
            
        end

        % Image clicked function: backBtnInMytickets
        function backBtnInMyticketsImageClicked(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Value changed function: myticket_inputID_checkBox
        function myticket_inputID_checkBoxValueChanged(app, event)
            
            value = app.myticket_inputID_checkBox.Value;
            if strcmp(value,"请选择")
                return
            end
            value=str2double(value);
            app.MyticketTrain.Text=app.myTickets(value,1).stationName;
            app.MyticketStartStation.Text=app.myTickets(value,2).stationName;
            app.MyticketToStation.Text=app.myTickets(value,3).stationName;
            app.MyticketStartTime.Text=app.myTickets(value,4).stationName;
            app.MyticketToTime.Text=app.myTickets(value,5).stationName;
        end

        % Button pushed function: MyticketCancelBtn
        function MyticketCancelBtnButtonPushed(app, event)
            ticketIndex = str2double(app.myticket_inputID_checkBox.Value);
            "删除一个车票"
            startStation=Station(app.MyticketStartStation.Text);
            toStation=Station(app.MyticketToStation.Text);
            trainCode=app.myTickets(ticketIndex,1).stationName;
            seatLevel=app.myTickets(ticketIndex,7).stationName;
            seatNum=str2double(app.myTickets(ticketIndex,6).stationName);
            
            app.trainDispatch.findTrain(app.getTheFirstTrain(trainCode)).bookTicket(startStation,toStation,seatLevel,-seatNum);
            app.myTickets(ticketIndex,:)=[];

            % "刷新UI"
            % "填充表格"
            app.UITable.Data={};
            [row_num, ~] = size(app.myTickets);

            for i=1:row_num
                app.UITable.Data=[app.UITable.Data;{i,app.myTickets(i,1).stationName}];
            end
            % "填充ID选择器"
            app.myticket_inputID_checkBox.Items=["请选择",string(1:row_num)];
        end

        % Button pushed function: MyticketRescheduleBtn
        function MyticketRescheduleBtnButtonPushed(app, event)
            "改签逻辑"
            "先取消"
            ticketIndex = str2double(app.myticket_inputID_checkBox.Value);
            startStation=Station(app.MyticketStartStation.Text);
            toStation=Station(app.MyticketToStation.Text);
            trainCode=app.myTickets(ticketIndex,1).stationName;
            seatLevel=app.myTickets(ticketIndex,7).stationName;
            seatNum=str2double(app.myTickets(ticketIndex,6).stationName);
            app.trainDispatch.findTrain(trainCode).bookTicket(startStation,toStation,seatLevel,-seatNum);
            app.myTickets(ticketIndex,:)=[];
            "套用之前查票的逻辑"
            startStation=Station(app.MyticketStartStation.Text);
            startStation.arrivalTime=app.trainDispatch.SysTime;
            toStation=Station(app.MyticketToStation.Text);
            SearchedTickets=app.trainDispatch.findAvailableTickets(startStation,toStation,0);
            tickets=strsplit(SearchedTickets,"-");
            app.showTickets(tickets(1:end-1));
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            
        end

        % Button pushed function: SortByEarilestButton
        function SortByEarilestButtonPushed(app, event)
            "最早到时"
            sortedTickets=[];
            train1=string(app.ticket1Train_10.Text);
            train2=string(app.ticket1Train_11.Text);
            train3=string(app.ticket1Train_6.Text);
            output1=app.sort3TicketsByEariest(train1,train2,train3,app.ticket1ToTime_10.Text,app.ticket1ToTime_11.Text,app.ticket1ToTime_6.Text);
            train4=string(app.ticket1Train_7.Text);
            train5=string(app.ticket1Train_8.Text);
            train6=string(app.ticket1Train_9.Text);
            output2=app.sort3TicketsByEariest(train4,train5,train6,app.ticket1ToTime_7.Text,app.ticket1ToTime_8.Text,app.ticket1ToTime_9.Text);
            sortedTickets=[output1,output2];
      
            app.showTickets(sortedTickets);
            
        end

        % Button pushed function: SortByTimeButton
        function SortByTimeButtonPushed(app, event)
            "最少耗时"
            train1=string(app.ticket1Train_10.Text);
            train2=string(app.ticket1Train_11.Text);
            train3=string(app.ticket1Train_6.Text);
            time1=app.ticket1StartTime_10.Text;
            time11=app.ticket1ToTime_10.Text;
            time2=app.ticket1StartTime_11.Text;
            time22=app.ticket1ToTime_11.Text;
            time3=app.ticket1StartTime_6.Text;
            time33=app.ticket1ToTime_6.Text;
            output1=app.sort3TicketsByAllTime(train1,train2,train3,time1,time11,time2,time22,time3,time33);
            train4=string(app.ticket1Train_7.Text);
            train5=string(app.ticket1Train_8.Text);
            train6=string(app.ticket1Train_9.Text);
            time1=app.ticket1StartTime_7.Text;
            time11=app.ticket1ToTime_7.Text;
            time2=app.ticket1StartTime_8.Text;
            time22=app.ticket1ToTime_8.Text;
            time3=app.ticket1StartTime_9.Text;
            time33=app.ticket1ToTime_9.Text;
            output2=app.sort3TicketsByAllTime(train4,train5,train6,time1,time11,time2,time22,time3,time33);
            sortedTickets=[output1,output2];
      
            app.showTickets(sortedTickets);
        end

        % Button pushed function: SortByPriceBtn
        function SortByPriceBtnButtonPushed(app, event)
            "最小价格"
            sortedTickets=[];
            train1=string(app.ticket1Train_10.Text);
            train2=string(app.ticket1Train_11.Text);
            train3=string(app.ticket1Train_6.Text);
            output1=app.sort3TicketsByEariest(train1,train2,train3,app.ticket1ToTime_10.Text,app.ticket1ToTime_11.Text,app.ticket1ToTime_6.Text);
            train4=string(app.ticket1Train_7.Text);
            train5=string(app.ticket1Train_8.Text);
            train6=string(app.ticket1Train_9.Text);
            output2=app.sort3TicketsByEariest(train4,train5,train6,app.ticket1ToTime_7.Text,app.ticket1ToTime_8.Text,app.ticket1ToTime_9.Text);
            sortedTickets=[output1,output2];
      
            app.showTickets(sortedTickets);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LaunchTab = uitab(app.TabGroup);
            app.LaunchTab.Title = '初始界面';
            app.Image2 = uiimage(app.LaunchTab);
            app.Image2.ImageSource = 'launch-bg-norad.png';
            app.Image3 = uiimage(app.LaunchTab);
            app.Image3.ImageSource = 'launch-bg-gradient-black.png';
            app.BuyTicketsButton = uibutton(app.LaunchTab, 'push');
            app.BuyTicketsButton.ButtonPushedFcn = createCallbackFcn(app, @BuyTicketsButtonPushed, true);
            app.BuyTicketsButton.Text = 'Buy  Tickets';
            app.LetsbookyourtrainwithjustatapLabel = uilabel(app.LaunchTab);
            app.LetsbookyourtrainwithjustatapLabel.Text = {'Let''s book your train'; 'with just a tap'; ''};
            app.QueryTab = uitab(app.TabGroup);
            app.QueryTab.Title = '查询界面';
            app.Image4 = uiimage(app.QueryTab);
            app.Image4.ImageSource = 'query-bg1.png';
            app.SelectDestinationPanel = uipanel(app.QueryTab);
            app.SelectDestinationPanel.Title = 'Select Destination';
            app.FromDropDownLabel = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel.Text = 'From';
            app.StartStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.StartStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.StartStationDropDown.Value = '南京南';
            app.FromDropDownLabel_2 = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel_2.Text = 'To';
            app.ToStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.ToStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.ToStationDropDown.Value = '溧阳';
            app.SearchButton = uibutton(app.QueryTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.HourSpinnerLabel = uilabel(app.QueryTab);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.QueryTab);
            app.HourSpinner.Value = 10;
            app.MinuteSpinnerLabel = uilabel(app.QueryTab);
            app.MinuteSpinnerLabel.Text = 'Minute';
            app.MinuteSpinner = uispinner(app.QueryTab);
            app.HTML = uihtml(app.QueryTab);
            app.HTML.HTMLSource = '<!DOCTYPE html><html>  <head>    <title>纯色背景</title>    <style>      body {        background-color: #ffffff;      }    </style>  </head>  <body>  </body></html>';
            app.isSelfTimeCheckBox = uicheckbox(app.QueryTab);
            app.isSelfTimeCheckBox.Text = '自定义时间';
            app.MyTicketsButton = uibutton(app.QueryTab, 'push');
            app.MyTicketsButton.ButtonPushedFcn = createCallbackFcn(app, @MyTicketsButtonPushed, true);
            app.MyTicketsButton.Text = 'My Tickets';
            app.Image13 = uiimage(app.QueryTab);
            app.Image13.ImageSource = 'queryRecent.png';
            app.CheckBox = uicheckbox(app.QueryTab);
            app.CheckBox.Text = '自动上车';
            app.CheckBox.Value = true;
            app.recentTrainCode = uilabel(app.QueryTab);
            app.recentTrainCode.Text = 'Label3';
            app.recentTrainTime = uilabel(app.QueryTab);
            app.recentTrainTime.Text = 'Label4';
            app.usrStausLabel = uilabel(app.QueryTab);
            app.usrStausLabel.Text = 'Label5';
            app.SearchedTicketsTab = uitab(app.TabGroup);
            app.SearchedTicketsTab.Title = '车票界面';
            app.Image5 = uiimage(app.SearchedTicketsTab);
            app.Image5.ImageSource = 'tickets_bg.png';
            app.Image7 = uiimage(app.SearchedTicketsTab);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = '图片 3@1x.png';
            app.Tickets_Start_station = uilabel(app.SearchedTicketsTab);
            app.Tickets_Start_station.Text = '起始站';
            app.Tickets_Stop_station = uilabel(app.SearchedTicketsTab);
            app.Tickets_Stop_station.Text = '终点站';
            app.TabDorT = uitabgroup(app.SearchedTicketsTab);
            app.TabD = uitab(app.TabDorT);
            app.TabD.Title = '直达班车';
            app.DGrid = uigridlayout(app.TabD);
            app.Panel_tickets_D_1 = uipanel(app.DGrid);
            app.Image8_9 = uiimage(app.Panel_tickets_D_1);
            app.Image8_9.ImageSource = 'ticket.png';
            app.ticket1StartStation_10 = uilabel(app.Panel_tickets_D_1);
            app.ticket1ToStation_10 = uilabel(app.Panel_tickets_D_1);
            app.ticket1Train_10 = uilabel(app.Panel_tickets_D_1);
            app.ticket1StartTime_10 = uilabel(app.Panel_tickets_D_1);
            app.ticket1StartTime_10.Text = 'Label2';
            app.ticket1ToTime_10 = uilabel(app.Panel_tickets_D_1);
            app.ticket1ToTime_10.Text = 'Label2';
            app.bookTicketBtn1 = uibutton(app.Panel_tickets_D_1, 'push');
            app.bookTicketBtn1.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn1ButtonPushed, true);
            app.bookTicketBtn1.Text = '订票';
            app.Label_2 = uilabel(app.Panel_tickets_D_1);
            app.Label_2.Text = '￥';
            app.Ticket_price_1 = uilabel(app.Panel_tickets_D_1);
            app.Ticket_price_1.Text = 'Label6';
            app.Panel_tickets_D_2 = uipanel(app.DGrid);
            app.Image8_10 = uiimage(app.Panel_tickets_D_2);
            app.Image8_10.ImageSource = 'ticket.png';
            app.ticket1StartStation_11 = uilabel(app.Panel_tickets_D_2);
            app.ticket1ToStation_11 = uilabel(app.Panel_tickets_D_2);
            app.ticket1Train_11 = uilabel(app.Panel_tickets_D_2);
            app.ticket1StartTime_11 = uilabel(app.Panel_tickets_D_2);
            app.ticket1StartTime_11.Text = 'Label2';
            app.ticket1ToTime_11 = uilabel(app.Panel_tickets_D_2);
            app.ticket1ToTime_11.Text = 'Label2';
            app.bookTicketBtn2 = uibutton(app.Panel_tickets_D_2, 'push');
            app.bookTicketBtn2.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn2ButtonPushed, true);
            app.bookTicketBtn2.Text = '订票';
            app.Ticket_price_2 = uilabel(app.Panel_tickets_D_2);
            app.Ticket_price_2.Text = 'Label6';
            app.Label_3 = uilabel(app.Panel_tickets_D_2);
            app.Label_3.Text = '￥';
            app.Panel_tickets_D_3 = uipanel(app.DGrid);
            app.Image8_5 = uiimage(app.Panel_tickets_D_3);
            app.Image8_5.ImageSource = 'ticket.png';
            app.ticket1StartStation_6 = uilabel(app.Panel_tickets_D_3);
            app.ticket1ToStation_6 = uilabel(app.Panel_tickets_D_3);
            app.ticket1Train_6 = uilabel(app.Panel_tickets_D_3);
            app.ticket1StartTime_6 = uilabel(app.Panel_tickets_D_3);
            app.ticket1StartTime_6.Text = 'Label2';
            app.ticket1ToTime_6 = uilabel(app.Panel_tickets_D_3);
            app.ticket1ToTime_6.Text = 'Label2';
            app.bookTicketBtn3 = uibutton(app.Panel_tickets_D_3, 'push');
            app.bookTicketBtn3.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn3ButtonPushed, true);
            app.bookTicketBtn3.Text = '订票';
            app.Label_4 = uilabel(app.Panel_tickets_D_3);
            app.Label_4.Text = '￥';
            app.Ticket_price_3 = uilabel(app.Panel_tickets_D_3);
            app.Ticket_price_3.Text = 'Label6';
            app.TabT = uitab(app.TabDorT);
            app.TabT.Title = '转乘班车';
            app.GridLayout = uigridlayout(app.TabT);
            app.TTrain1 = uipanel(app.GridLayout);
            app.Image8_6 = uiimage(app.TTrain1);
            app.Image8_6.ImageSource = 'ticket.png';
            app.ticket1StartStation_7 = uilabel(app.TTrain1);
            app.ticket1ToStation_7 = uilabel(app.TTrain1);
            app.ticket1Train_7 = uilabel(app.TTrain1);
            app.ticket1StartTime_7 = uilabel(app.TTrain1);
            app.ticket1StartTime_7.Text = 'Label2';
            app.ticket1ToTime_7 = uilabel(app.TTrain1);
            app.ticket1ToTime_7.Text = 'Label2';
            app.bookTicketBtn4 = uibutton(app.TTrain1, 'push');
            app.bookTicketBtn4.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn4ButtonPushed, true);
            app.bookTicketBtn4.Text = '订票';
            app.Label_5 = uilabel(app.TTrain1);
            app.Label_5.Text = '￥';
            app.Ticket_price_4 = uilabel(app.TTrain1);
            app.Ticket_price_4.Text = 'Label6';
            app.TTrain2 = uipanel(app.GridLayout);
            app.Image8_7 = uiimage(app.TTrain2);
            app.Image8_7.ImageSource = 'ticket.png';
            app.ticket1StartStation_8 = uilabel(app.TTrain2);
            app.ticket1ToStation_8 = uilabel(app.TTrain2);
            app.ticket1Train_8 = uilabel(app.TTrain2);
            app.ticket1StartTime_8 = uilabel(app.TTrain2);
            app.ticket1StartTime_8.Text = 'Label2';
            app.ticket1ToTime_8 = uilabel(app.TTrain2);
            app.ticket1ToTime_8.Text = 'Label2';
            app.bookTicketBtn5 = uibutton(app.TTrain2, 'push');
            app.bookTicketBtn5.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn5ButtonPushed, true);
            app.bookTicketBtn5.Text = '订票';
            app.Label_6 = uilabel(app.TTrain2);
            app.Label_6.Text = '￥';
            app.Ticket_price_5 = uilabel(app.TTrain2);
            app.Ticket_price_5.Text = 'Label6';
            app.TTrain3 = uipanel(app.GridLayout);
            app.Image8_8 = uiimage(app.TTrain3);
            app.Image8_8.ImageSource = 'ticket.png';
            app.ticket1StartStation_9 = uilabel(app.TTrain3);
            app.ticket1ToStation_9 = uilabel(app.TTrain3);
            app.ticket1Train_9 = uilabel(app.TTrain3);
            app.ticket1StartTime_9 = uilabel(app.TTrain3);
            app.ticket1StartTime_9.Text = 'Label2';
            app.ticket1ToTime_9 = uilabel(app.TTrain3);
            app.ticket1ToTime_9.Text = 'Label2';
            app.bookTicketBtn6 = uibutton(app.TTrain3, 'push');
            app.bookTicketBtn6.ButtonPushedFcn = createCallbackFcn(app, @bookTicketBtn6ButtonPushed, true);
            app.bookTicketBtn6.Text = '订票';
            app.Label_7 = uilabel(app.TTrain3);
            app.Label_7.Text = '￥';
            app.Ticket_price_6 = uilabel(app.TTrain3);
            app.Ticket_price_6.Text = 'Label6';
            app.Image11_2 = uiimage(app.SearchedTicketsTab);
            app.Image11_2.ImageClickedFcn = createCallbackFcn(app, @BackBtnInTicketPushed, true);
            app.Image11_2.ImageSource = '返回按钮@1x.png';
            app.SortByTimeButton = uibutton(app.SearchedTicketsTab, 'push');
            app.SortByTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SortByTimeButtonPushed, true);
            app.SortByTimeButton.Text = '最少耗时';
            app.SortByEarilestButton = uibutton(app.SearchedTicketsTab, 'push');
            app.SortByEarilestButton.ButtonPushedFcn = createCallbackFcn(app, @SortByEarilestButtonPushed, true);
            app.SortByEarilestButton.Text = '最早到时';
            app.SortByPriceBtn = uibutton(app.SearchedTicketsTab, 'push');
            app.SortByPriceBtn.ButtonPushedFcn = createCallbackFcn(app, @SortByPriceBtnButtonPushed, true);
            app.SortByPriceBtn.Text = '最小价格';
            app.BookTab = uitab(app.TabGroup);
            app.BookTab.Title = '订票界面';
            app.Image6 = uiimage(app.BookTab);
            app.Image6.ImageSource = 'book-bg-3.png';
            app.BookBtn = uibutton(app.BookTab, 'push');
            app.BookBtn.ButtonPushedFcn = createCallbackFcn(app, @BookBtnButtonPushed, true);
            app.BookBtn.Text = '订票';
            app.bookticketStartStation = uilabel(app.BookTab);
            app.ticket1ToStation_2 = uilabel(app.BookTab);
            app.ticket1Train_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2.Text = 'Label2';
            app.ticket1ToTime_2 = uilabel(app.BookTab);
            app.ticket1ToTime_2.Text = 'Label2';
            app.Image11 = uiimage(app.BookTab);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @BackBtnInBookButtonPushed, true);
            app.Image11.ImageSource = '返回按钮@1x.png';
            app.Label = uilabel(app.BookTab);
            app.Label.Text = '添加乘客';
            app.DropDown_SelectPassanger = uidropdown(app.BookTab);
            app.DropDown_SelectPassanger.Items = {'就我自己', '小明', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown_SelectPassanger.Value = '就我自己';
            app.NoSeatVIPICON = uiimage(app.BookTab);
            app.NoSeatVIPICON.ImageSource = 'arcoDesign-close@2x.png';
            app.NoSeatNormalICON_1 = uiimage(app.BookTab);
            app.NoSeatNormalICON_1.ImageSource = 'arcoDesign-close@2x.png';
            app.NoSeatNormalICON_2 = uiimage(app.BookTab);
            app.NoSeatNormalICON_2.ImageSource = 'arcoDesign-close@2x.png';
            app.normalSeatImg = uiimage(app.BookTab);
            app.normalSeatImg.ImageClickedFcn = createCallbackFcn(app, @normalSeatImgImageClicked, true);
            app.normalSeatImg.ImageSource = '经济舱@1x.png';
            app.VIPSeatImg = uiimage(app.BookTab);
            app.VIPSeatImg.ImageClickedFcn = createCallbackFcn(app, @VIPSeatImgImageClicked, true);
            app.VIPSeatImg.ImageSource = '商务舱@1x.png';
            app.selectNormalICON = uiimage(app.BookTab);
            app.selectNormalICON.ImageSource = 'select.png';
            app.selectVIPICON = uiimage(app.BookTab);
            app.selectVIPICON.ImageSource = 'select.png';
            app.UsrTab = uitab(app.TabGroup);
            app.UsrTab.Title = '用户界面';
            app.Image12 = uiimage(app.UsrTab);
            app.Image12.ImageSource = 'myticketBg.png';
            app.usrTicket = uipanel(app.UsrTab);
            app.Image8_11 = uiimage(app.usrTicket);
            app.Image8_11.ImageSource = 'ticket.png';
            app.MyticketStartStation = uilabel(app.usrTicket);
            app.MyticketToStation = uilabel(app.usrTicket);
            app.MyticketTrain = uilabel(app.usrTicket);
            app.MyticketStartTime = uilabel(app.usrTicket);
            app.MyticketStartTime.Text = 'Label2';
            app.MyticketToTime = uilabel(app.usrTicket);
            app.MyticketToTime.Text = 'Label2';
            app.MyticketRescheduleBtn = uibutton(app.usrTicket, 'push');
            app.MyticketRescheduleBtn.ButtonPushedFcn = createCallbackFcn(app, @MyticketRescheduleBtnButtonPushed, true);
            app.MyticketRescheduleBtn.Text = '改签';
            app.MyticketCancelBtn = uibutton(app.usrTicket, 'push');
            app.MyticketCancelBtn.ButtonPushedFcn = createCallbackFcn(app, @MyticketCancelBtnButtonPushed, true);
            app.MyticketCancelBtn.Text = '取消';
            app.GetTrainBtn = uibutton(app.usrTicket, 'push');
            app.GetTrainBtn.Text = '上车';
            app.UITable = uitable(app.UsrTab);
            app.UITable.ColumnName = {'ID'; '列车号'};
            app.UITable.RowName = {};
            app.backBtnInMytickets = uiimage(app.UsrTab);
            app.backBtnInMytickets.ImageClickedFcn = createCallbackFcn(app, @backBtnInMyticketsImageClicked, true);
            app.backBtnInMytickets.ImageSource = '返回按钮@1x.png';
            app.InputIDDropDownLabel = uilabel(app.UsrTab);
            app.InputIDDropDownLabel.Text = 'Input ID:';
            app.myticket_inputID_checkBox = uidropdown(app.UsrTab);
            app.myticket_inputID_checkBox.Items = {};
            app.myticket_inputID_checkBox.ValueChangedFcn = createCallbackFcn(app, @myticket_inputID_checkBoxValueChanged, true);
            app.myticket_inputID_checkBox.Value = {};
            app.BackBtnInTicket = uibutton(app.UIFigure, 'push');
            app.BackBtnInTicket.ButtonPushedFcn = createCallbackFcn(app, @BackBtnInTicketPushed, true);
            app.BackBtnInTicket.Text = {'返回'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end