classdef query < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        passagenerStatus  matlab.ui.control.Label
        Label             matlab.ui.control.Label
        passagenerName    matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        trainsPanel       matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        ticketHtml3       matlab.ui.control.HTML
        ticketHtml2       matlab.ui.control.HTML
        ticketHtml1       matlab.ui.control.HTML
        HTML              matlab.ui.control.HTML
        queryBtn          matlab.ui.control.Button
        trainDate         matlab.ui.control.DatePicker
        toLoc             matlab.ui.control.EditField
        Image             matlab.ui.control.Image
        fromLoc           matlab.ui.control.EditField
    end

    
    properties (Access = public)
        passengerBind % Description
    end
    
    properties (Access = private)
        HTMLtemplethead="<!DOCTYPE html><html><head>	<style>		table {			border-collapse: collapse;			width: 100%;      height:20px;		}		td {			padding: 2px;			text-align: center;		}	</style></head>"
        HTMLtemplet="<body>	<table>		<tr>			<td>列车号</td>			<td>到站时间</td><!-- 			<td>状态</td> -->		</tr>		<tr>			<td>%s</td>			<td>%s</td><!-- 			<td>候车</td> -->		</tr>	</table></body></html>"
    end
    
    methods (Access = public)
        function obj = bindPassanger(app,pass)
            app.passengerBind=pass;
            app.passagenerName.Text=pass.passangerName;
            
        end
       
        function results = DisplayTrains(app)
            theTrain=app.passengerBind.myTickets(1);
            tempHtml=sprintf(app.HTMLtemplet,theTrain.ticketTrain.trainCode,theTrain.fromStation.arrivalTime)
            app.ticketHtml1.HTMLSource=app.HTMLtemplethead+tempHtml
            
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fromLoc = uieditfield(app.UIFigure, 'text');
            app.fromLoc.Placeholder = '12312';
            app.fromLoc.Value = '始发地';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '圆圈箭头.png';
            app.toLoc = uieditfield(app.UIFigure, 'text');
            app.toLoc.Placeholder = '12312';
            app.toLoc.Value = '目的地';
            app.trainDate = uidatepicker(app.UIFigure);
            app.trainDate.DisplayFormat = 'uuuu年 M月 d日';
            app.trainDate.Placeholder = '2017-08-08';
            app.queryBtn = uibutton(app.UIFigure, 'push');
            app.queryBtn.Text = '查询车票';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr/>';
            app.trainsPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.trainsPanel);
            app.ticketHtml1 = uihtml(app.GridLayout);
            app.ticketHtml1.HTMLSource = '<!DOCTYPE html><html><head>	<style>		table {			border-collapse: collapse;			width: 100%;      height:20px;		}		td {			padding: 2px;			text-align: center;		}	</style></head><body>	<table>		<tr>			<td>列车号</td>			<td>到站时间</td><!-- 			<td>状态</td> -->		</tr>		<tr>			<td>G112</td>			<td>7：00</td><!-- 			<td>候车</td> -->		</tr>	</table></body></html>';
            app.ticketHtml2 = uihtml(app.GridLayout);
            app.ticketHtml3 = uihtml(app.GridLayout);
            app.Panel = uipanel(app.UIFigure);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '乘客：';
            app.passagenerName = uilabel(app.Panel);
            app.passagenerName.Text = '???';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '状态：';
            app.passagenerStatus = uilabel(app.Panel);
            app.passagenerStatus.Text = '未上车';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = query

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end